classdef getapp_02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        recordingSwitch         matlab.ui.control.Switch
        recordingSwitchLabel    matlab.ui.control.Label
        nameEditField           matlab.ui.control.EditField
        nameEditFieldLabel      matlab.ui.control.Label
        recordRGBSwitch         matlab.ui.control.Switch
        recordRGBSwitchLabel    matlab.ui.control.Label
        CLOSEButton             matlab.ui.control.Button
        previewSwitch           matlab.ui.control.Switch
        previewSwitchLabel      matlab.ui.control.Label
        ageEditField            matlab.ui.control.NumericEditField
        ageEditFieldLabel       matlab.ui.control.Label
        beardDropDown           matlab.ui.control.DropDown
        beardDropDownLabel      matlab.ui.control.Label
        sexButtonGroup          matlab.ui.container.ButtonGroup
        womanButton             matlab.ui.control.RadioButton
        manButton               matlab.ui.control.RadioButton
        exerciseDropDown        matlab.ui.control.DropDown
        exerciseDropDownLabel   matlab.ui.control.Label
        statusDropDown          matlab.ui.control.DropDown
        statusDropDownLabel     matlab.ui.control.Label
        IDnumberEditField       matlab.ui.control.NumericEditField
        IDnumberEditFieldLabel  matlab.ui.control.Label
        STARTButton             matlab.ui.control.Button
        STOPButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %global variables for communication with script faceRecord.m
            global adr recBut sex status exercise beard rgbRec k kinAdr
            
            %adress where records will be saved
            adr = 'C:\Users\kuba8\MATLAB Drive\projekt oblicej\datapropouziti\';
            
            %adress with Mex file
            kinAdr='C:\Users\kuba8\MATLAB Drive\github_repo\Mex'   
            
            %setting default values for RGB recording (rgbRec) and for
            %ending recording (k)
            rgbRec=0; k=1;
            
            %declaration of recording button to a variable
            recBut=app.recordingSwitch
            recBut.Value='Off'

            %changing status for buttons
            app.previewSwitch.Enable=false            
            app.recordRGBSwitch.Enable=true
            app.STOPButton.Enable=false
            
            %saving default values set in GUI
            sex='M';
            status='Z';
            exercise='b';
            beard = 'No';
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            %this button end recording and reset the GUI
            global k2 k stop rgbRec sex status exercise beard
            k=1;
            stop=1;
            
            %stop preview
            delete(figure(1));
            
            %change status of some buttons and switches
            app.recordRGBSwitch.Enable=true
            app.CLOSEButton.Enable=true
            app.beardDropDown.Enable='on'
            app.previewSwitch.Enable=false
            app.STARTButton.Enable=true
            app.STOPButton.Enable=false
            
            %reset values in GUI and make it ready for next recodr
            app.IDnumberEditField.Value=[0]
            app.nameEditField.Value=""
            app.ageEditField.Value=[0]
            app.manButton.Value=true
            app.exerciseDropDown.Value='baring teeth normal'
            app.statusDropDown.Value="healthy"
            app.beardDropDown.Value="No"            
            app.recordingSwitch.Value="Off"
            app.recordRGBSwitch.Value="Off"
            app.previewSwitch.Value="Off"
            
            rgbRec=0;
            
            sex='M';
            status='Z';
            exercise='b';
            beard = 'No';
            
            %closing kinect
            k2.delete
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %this button start recording
            global adr k2 stop k color text kinAdr
            
            %set default values for manipulating with preview (stop) and
            %for stopping recording (k)
            k=0;
            stop=0;
            
            %inniciating kinect
            addpath(kinAdr)
            k2 = Kin2('color','HDface');
            
            %change status of some buttons and switches
            app.recordRGBSwitch.Enable=false;
            app.CLOSEButton.Enable=false;
            app.previewSwitch.Enable=true;
            app.STARTButton.Enable=false
            app.STOPButton.Enable=true
            
            pause(1);
            
            %get the first picture of pacinet
            img=color;
            
            %call the skript for recording
            run('faceRecord');
            
            %save the first pisture of pacinet
            save([adr text '\foto'],'img');
        end

        % Value changed function: IDnumberEditField
        function IDnumberEditFieldValueChanged(app, event)
            %get ID number of pacient
            global id
            id = app.IDnumberEditField.Value;      
        end

        % Value changed function: statusDropDown
        function statusDropDownValueChanged(app, event)
            %get status of pacient and assign the shortcut
            global status
            status = app.statusDropDown.Value;
            switch(status)
                case 'healthy'
                    status='Z'
                case 'simulated paralyze'
                    status='S'
                case 'paralyzed'
                    status='P'
            end
        end

        % Value changed function: exerciseDropDown
        function exerciseDropDownValueChanged(app, event)
            %get provided exercise and assign the shortcut
            global exercise
            exercise = app.exerciseDropDown.Value;
            switch(exercise)
                case 'baring teeth normal'
                    exercise='b';
                case 'baring teeth with right side paralyzed'
                    exercise='bar';
                case 'baring teeth with left side paralyzed'
                    exercise='bal';
                case 'closing eyes normal'
                    exercise='en';
                case 'closing eyes with right side paralyzed'
                    exercise='er';
                case 'closing eyes with left side paralyzed'
                    exercise='el';
            end 
        end

        % Selection changed function: sexButtonGroup
        function sexButtonGroupSelectionChanged(app, event)
            %get sex of pacient
            global sex beard
            sex = app.sexButtonGroup.SelectedObject;
            sex=sex.Text
                switch(sex)
                    case 'man'
                        sex='M'
                        app.beardDropDown.Enable='on'
                    case 'woman'
                        sex='F'
                        %if woman is chosen, beard drop down ges enable and
                        %value automatically would be "No"
                        app.beardDropDown.Enable='off';
                        app.beardDropDown.Value='No'
                        beard='No'
                end
        end

        % Value changed function: beardDropDown
        function beardDropDownValueChanged(app, event)
            %get information about pacient's beard
            global beard
            beard = app.beardDropDown.Value;
        end

        % Value changed function: ageEditField
        function ageEditFieldValueChanged(app, event)
            %get age of the pacient
            global age
            age = app.ageEditField.Value;
        end

        % Value changed function: previewSwitch
        function previewSwitchValueChanged(app, event)
            %this switch open and close preview
            value = app.previewSwitch.Value;
            global stop 
                switch(value)
                    case 'On'
                        stop=1;      
                    case 'Off'
                        stop=0;
                        pause(0.01)
                        delete(figure(1));
                end
        end

        % Button pushed function: CLOSEButton
        function CLOSEButtonPushed(app, event)
            %this switch close preview, if it was open, and close GUI
            delete(figure(1)); 
            delete(app.UIFigure)
        end

        % Value changed function: recordRGBSwitch
        function recordRGBSwitchValueChanged(app, event)
            %this switch carry the information about saving RGB
            global rgbRec
            value = app.recordRGBSwitch.Value;
            switch(value)
                    case 'On'
                        rgbRec=1;                       
                    case 'Off'
                        rgbRec=0;
                end
        end

        % Value changed function: nameEditField
        function nameEditFieldValueChanged(app, event)
            %get the name of the pacient
            global name
            name = app.nameEditField.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.IDnumberEditFieldLabel = uilabel(app.UIFigure);
            app.IDnumberEditFieldLabel.Text = 'ID number';
            app.IDnumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.IDnumberEditField.ValueChangedFcn = createCallbackFcn(app, @IDnumberEditFieldValueChanged, true);
            app.statusDropDownLabel = uilabel(app.UIFigure);
            app.statusDropDownLabel.Text = 'status';
            app.statusDropDown = uidropdown(app.UIFigure);
            app.statusDropDown.Items = {'healthy', 'simulated paralyze', 'paralyzed'};
            app.statusDropDown.ValueChangedFcn = createCallbackFcn(app, @statusDropDownValueChanged, true);
            app.statusDropDown.Value = 'healthy';
            app.exerciseDropDownLabel = uilabel(app.UIFigure);
            app.exerciseDropDownLabel.Text = 'exercise';
            app.exerciseDropDown = uidropdown(app.UIFigure);
            app.exerciseDropDown.Items = {'baring teeth normal', 'baring teeth with right side paralyzed', 'baring teeth with left side paralyzed', 'closing eyes normal', 'closing eyes with right side paralyzed', 'closing eyes with left side paralyzed'};
            app.exerciseDropDown.ValueChangedFcn = createCallbackFcn(app, @exerciseDropDownValueChanged, true);
            app.exerciseDropDown.Value = 'baring teeth normal';
            app.sexButtonGroup = uibuttongroup(app.UIFigure);
            app.sexButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @sexButtonGroupSelectionChanged, true);
            app.sexButtonGroup.Title = 'sex';
            app.manButton = uiradiobutton(app.sexButtonGroup);
            app.manButton.Text = 'man';
            app.manButton.Value = true;
            app.womanButton = uiradiobutton(app.sexButtonGroup);
            app.womanButton.Text = 'woman';
            app.beardDropDownLabel = uilabel(app.UIFigure);
            app.beardDropDownLabel.Text = 'beard';
            app.beardDropDown = uidropdown(app.UIFigure);
            app.beardDropDown.Items = {'No', 'Yes'};
            app.beardDropDown.ValueChangedFcn = createCallbackFcn(app, @beardDropDownValueChanged, true);
            app.beardDropDown.Value = 'No';
            app.ageEditFieldLabel = uilabel(app.UIFigure);
            app.ageEditFieldLabel.Text = 'age';
            app.ageEditField = uieditfield(app.UIFigure, 'numeric');
            app.ageEditField.Limits = [0 100];
            app.ageEditField.ValueChangedFcn = createCallbackFcn(app, @ageEditFieldValueChanged, true);
            app.previewSwitchLabel = uilabel(app.UIFigure);
            app.previewSwitchLabel.Enable = 'off';
            app.previewSwitchLabel.Text = 'preview';
            app.previewSwitch = uiswitch(app.UIFigure, 'slider');
            app.previewSwitch.ValueChangedFcn = createCallbackFcn(app, @previewSwitchValueChanged, true);
            app.previewSwitch.Enable = 'off';
            app.CLOSEButton = uibutton(app.UIFigure, 'push');
            app.CLOSEButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEButtonPushed, true);
            app.CLOSEButton.Text = 'CLOSE';
            app.recordRGBSwitchLabel = uilabel(app.UIFigure);
            app.recordRGBSwitchLabel.Text = 'record RGB';
            app.recordRGBSwitch = uiswitch(app.UIFigure, 'slider');
            app.recordRGBSwitch.ValueChangedFcn = createCallbackFcn(app, @recordRGBSwitchValueChanged, true);
            app.nameEditFieldLabel = uilabel(app.UIFigure);
            app.nameEditFieldLabel.Text = 'name';
            app.nameEditField = uieditfield(app.UIFigure, 'text');
            app.nameEditField.ValueChangedFcn = createCallbackFcn(app, @nameEditFieldValueChanged, true);
            app.recordingSwitchLabel = uilabel(app.UIFigure);
            app.recordingSwitchLabel.Enable = 'off';
            app.recordingSwitchLabel.Text = 'recording';
            app.recordingSwitch = uiswitch(app.UIFigure, 'slider');
            app.recordingSwitch.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = getapp_02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end