classdef tabulkainfa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        nameLabel                      matlab.ui.control.Label
        ageLabel                       matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        beardLabel                     matlab.ui.control.Label
        nameoftherecordEditField       matlab.ui.control.EditField
        nameoftherecordEditFieldLabel  matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        lenghtoftherecordLabel         matlab.ui.control.Label
        numberofframesLabel            matlab.ui.control.Label
        exerciseLabel                  matlab.ui.control.Label
        IDLabel                        matlab.ui.control.Label
        clientstatusLabel              matlab.ui.control.Label
        sexLabel                       matlab.ui.control.Label
        closeButton                    matlab.ui.control.Button
        InformationaboutrecordLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
            delete(app.UIFigure)
        end

        % Value changed function: nameoftherecordEditField
        function TextAreaValueChanged(app, event)
            %promena pro predani nazvu slozky skriptu 'readapp'
            global slozka
            slozka=app.nameoftherecordEditField.Value
            
            %spusti skript readapp ve slozce C:\Users\kuba8\Desktop\studium\Práce na ústavu\projekt oblicej\aplikace
            run('readapp.m')
            
            %vypíše potřebná data, která poskytne skript
            app.Label.Text=sex
            app.Label_2.Text=status
            app.Label_3.Text=id
            app.Label_4.Text=exercise
            app.Label_5.Text=frames
            app.Label_6.Text=[time ' s']
            app.Label_7.Text=beard
            app.Label_8.Text=[age ' years']
            app.Label_9.Text=name
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InformationaboutrecordLabel = uilabel(app.UIFigure);
            app.InformationaboutrecordLabel.Text = 'Information about record';
            app.closeButton = uibutton(app.UIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            app.sexLabel = uilabel(app.UIFigure);
            app.sexLabel.Text = 'sex';
            app.clientstatusLabel = uilabel(app.UIFigure);
            app.clientstatusLabel.Text = 'client status';
            app.IDLabel = uilabel(app.UIFigure);
            app.IDLabel.Text = 'ID';
            app.exerciseLabel = uilabel(app.UIFigure);
            app.exerciseLabel.Text = 'exercise';
            app.numberofframesLabel = uilabel(app.UIFigure);
            app.numberofframesLabel.Text = 'number of frames';
            app.lenghtoftherecordLabel = uilabel(app.UIFigure);
            app.lenghtoftherecordLabel.Text = 'lenght of the record';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.nameoftherecordEditFieldLabel = uilabel(app.UIFigure);
            app.nameoftherecordEditFieldLabel.Text = 'name of the record';
            app.nameoftherecordEditField = uieditfield(app.UIFigure, 'text');
            app.nameoftherecordEditField.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.beardLabel = uilabel(app.UIFigure);
            app.beardLabel.Text = 'beard';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.ageLabel = uilabel(app.UIFigure);
            app.ageLabel.Text = 'age';
            app.nameLabel = uilabel(app.UIFigure);
            app.nameLabel.Text = 'name';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tabulkainfa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end